classdef proje < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IZURobotTeknolojileriDersiProjeOdeviUIFigure  matlab.ui.Figure
        DorusalHzSliderLabel  matlab.ui.control.Label
        DogrusalHizSlider     matlab.ui.control.Slider
        graph_aci             matlab.ui.control.UIAxes
        graph_hata            matlab.ui.control.UIAxes
        graph_tekerlek_hiz    matlab.ui.control.UIAxes
        graph_kontrol         matlab.ui.control.UIAxes
        graph_vl              matlab.ui.control.UIAxes
        graph_vr              matlab.ui.control.UIAxes
        SimulasyonButton      matlab.ui.control.Button
        KpEditFieldLabel      matlab.ui.control.Label
        Kp                    matlab.ui.control.NumericEditField
        KiEditFieldLabel      matlab.ui.control.Label
        Ki                    matlab.ui.control.NumericEditField
        KdEditField_2Label    matlab.ui.control.Label
        Kd                    matlab.ui.control.NumericEditField
        AKnobLabel            matlab.ui.control.Label
        AciKnob               matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = public)
        myRobot % robotics playground objesi olacak
        running=0; % butona basilip programin calisir durumda oldugunu gosteren degisken
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.myRobot = mlrobot; % program basladiginda bir robot nesnesi olusturur.
        end

        % Button pushed function: SimulasyonButton
        function SimulasyonButtonPushed(app, event)
            
            if (app.running==1)
                
                app.SimulasyonButton.Text = 'Simulasyonu Başlat';
                robot = app.myRobot;
                mlrobotstop(robot); % devam eden simulasyonu durdur
                app.running=0;
            else
                app.SimulasyonButton.Text = 'Durdur';
                app.running=1;
                robot = app.myRobot;
                %mlrobotstop(robot); % devam eden simulasyonu durdur
                mlrobotstart(robot); % simulasyonu baslat               
           
            for i=1:30
                mlrobotsetmotorvoltage(robot,'left',5);%Tekerleklerinbirinediğerindenfazla
                mlrobotsetmotorvoltage(robot,'right',3);% hız verirsen araç döner
                angle(i)= mlrobotreadcompassangle(robot);%pusuladangelenveri
                pause(0.1);%durudurma
            end
            angle=robot;%gelenveriyituttuk
            deger=integer(angle);%stringbirifadeyiçevirmekiçin
            save angle;%değerikaydettik
            angle=str2num(['unit8(',str13,')']);%integercinsindeyazdık
            say= int2str(angle)%değerisaydeğişkeninde tanımladık
            Kp=('mlrobot[tekerlek_hiz]');%pidcontrollerdeğeri
            a pid(Kp);%pid 
            Ki=('mlrobot[tekerlek_hiz]');%pidcontrollerdeğeri
            b pid(Ki);%pid
            Kd=('mlrobot[tekerlek_hiz]');%pidcontrollerdeğeri
            c pid(Kd);%pid
            
            color = rand(1,3);
            line(app.graph_aci, 0:1/1000:10, rand(1,1)*sin(2*pi*[0:1/1000:10]),'Color',color)
            grid on; hold on;
            color= rand (2, 3);
            line(app.graph_tekerlek_hiz, 0:1/1000:10, rand(2,3)*app.graph_tekerlek_hiz(2*pi*[u]),'Color',color)
            grid on; hold on;
            color = rand(1,5);
            line(app.graph_hata, [app.graph_aci],[u]&&(app.graph_hata), 'Color',color)
            grid on; hold on;
            color = rand(2,3);
            line(app.graph_kontrol, [u]&&i (app.graph_tekerlek_hiz), [pid]  , 'Color',color)
            grid on; hold on;
            color = rand(1,3);
            %%line(app.graph_vl, 0:1/1000:10, rand(1,1)*sin(2*pi*[0:1/1000:10]),'Color',color)
            grid on; hold on;
            
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IZURobotTeknolojileriDersiProjeOdeviUIFigure = uifigure('Visible', 'off');
            app.IZURobotTeknolojileriDersiProjeOdeviUIFigure.Name = 'IZU Robot Teknolojileri Dersi Proje Odevi';
            app.DorusalHzSliderLabel = uilabel(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure);
            app.DorusalHzSliderLabel.Text = 'Doğrusal Hız';
            app.DogrusalHizSlider = uislider(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure);
            app.DogrusalHizSlider.Limits = [0 3];
            app.DogrusalHizSlider.MinorTicks = [];
            app.graph_aci = uiaxes(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure);
            title(app.graph_aci, 'Açı [rad]')
            xlabel(app.graph_aci, 't')
            app.graph_hata = uiaxes(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure);
            title(app.graph_hata, 'hata [rad]')
            xlabel(app.graph_hata, 't')
            app.graph_tekerlek_hiz = uiaxes(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure);
            title(app.graph_tekerlek_hiz, 'Tekerlek Açısal Hızları [rad/s]')
            xlabel(app.graph_tekerlek_hiz, 't')
            app.graph_kontrol = uiaxes(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure);
            title(app.graph_kontrol, 'Kontrol Sinyali (u)')
            xlabel(app.graph_kontrol, 't')
            app.graph_vl = uiaxes(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure);
            title(app.graph_vl, 'vl [volt]')
            xlabel(app.graph_vl, 't')
            app.graph_vr = uiaxes(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure);
            title(app.graph_vr, 'vr [volt]')
            xlabel(app.graph_vr, 't')
            app.SimulasyonButton = uibutton(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure, 'push');
            app.SimulasyonButton.ButtonPushedFcn = createCallbackFcn(app, @SimulasyonButtonPushed, true);
            app.SimulasyonButton.Text = 'Simulasyonu Başlat';
            app.KpEditFieldLabel = uilabel(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.Kp = uieditfield(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure, 'numeric');
            app.Kp.Value = 1;
            app.KiEditFieldLabel = uilabel(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.Ki = uieditfield(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure, 'numeric');
            app.KdEditField_2Label = uilabel(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure);
            app.KdEditField_2Label.Text = 'Kd';
            app.Kd = uieditfield(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure, 'numeric');
            app.AKnobLabel = uilabel(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure);
            app.AKnobLabel.Text = 'Açı';
            app.AciKnob = uiknob(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure, 'discrete');
            app.AciKnob.Items = {'0', 'pi/8', 'pi/4', '3*pi/8', 'pi/2', '5*pi/8', '6*pi/8', '7*pi/8', 'pi', '9*pi/8', '5*pi/4', '11*pi/8', '3*pi/2', '13*pi/8', '7*pi/4', '15*pi/8', '2*pi'};
            app.AciKnob.Value = '0';
            % Show the figure after all components are created
            app.IZURobotTeknolojileriDersiProjeOdeviUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proje

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IZURobotTeknolojileriDersiProjeOdeviUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IZURobotTeknolojileriDersiProjeOdeviUIFigure)
        end
    end
end